classdef generate_2d_data < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Generate2DDataUIFigure  matlab.ui.Figure
        data_axes               matlab.ui.control.UIAxes
        class_table             matlab.ui.control.Table
        axes_table              matlab.ui.control.Table
        ControlPanel            matlab.ui.container.Panel
        export_button           matlab.ui.control.Button
        add_class_button        matlab.ui.control.Button
        remove_class_button     matlab.ui.control.Button
        class_type_drop_down    matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        colors = {};

        curr_counter=1;
        max_class_count = 7;
        x_min = -10;
        x_max = 10;
        y_min = -10;
        y_max = 10;
        start_num_points = 200;
        start_x_mean = 0;
        start_y_mean = 0;
        start_x_size = 0.5;
        start_y_size = 0.5;
        start_rot_angle = 0;
        start_thickness = 0.5;
        class_info = struct('class',[],'type',[],'num_points',[],'x_mean',[],'y_mean',[],'x_size',[],'y_size',[],'rot_angle',[],'thickness',[]);
        class_data = {};
        class_plot = {};
        
        current_selected_row = 0;
    end
    
    methods (Access = private)
        
        function data = generate_class_data(app,class_info)
            data = [];
            switch class_info.type
                case 'Gaussian'
                    mu = [class_info.x_mean class_info.y_mean];
                    sigma = [class_info.x_size 0;0 class_info.y_size];
                    data = mvnrnd(mu,sigma,class_info.num_points);
                    rot_mat = [cosd(class_info.rot_angle) sind(class_info.rot_angle);-sind(class_info.rot_angle) cosd(class_info.rot_angle)];
                    data = (rot_mat*data')';
                case 'Uniform'
                    data = zeros(class_info.num_points,2);
                    curr_i = 1;
                    while curr_i<=class_info.num_points
                        curr_point = [5*(rand-0.5)*class_info.x_size 5*(rand-0.5)*class_info.y_size];
                        data(curr_i,:) = curr_point;
                        curr_i = curr_i + 1;
                    end
                    rot_mat = [cosd(class_info.rot_angle) sind(class_info.rot_angle);-sind(class_info.rot_angle) cosd(class_info.rot_angle)];
                    data = (rot_mat*data')';
                    data(:,1) = data(:,1) + class_info.x_mean;
                    data(:,2) = data(:,2) + class_info.y_mean;
                    
                case 'Circular'
                    data = zeros(class_info.num_points,2);
                    curr_i = 1;
                    while curr_i<=class_info.num_points
                        curr_point = [5*(rand-0.5)*(class_info.x_size+class_info.thickness) 5*(rand-0.5)*(class_info.y_size+class_info.thickness)];
                        buffer = (curr_point(1)).^2/(2.5*class_info.x_size).^2 + (curr_point(2)).^2/(2.5*class_info.y_size).^2;
                        if buffer>=1 && buffer <= 1 + class_info.thickness
                            data(curr_i,:) = curr_point;
                            curr_i = curr_i + 1;
                            continue;
                        else
                            continue;
                        end
                    end
                    rot_mat = [cosd(class_info.rot_angle) sind(class_info.rot_angle);-sind(class_info.rot_angle) cosd(class_info.rot_angle)];
                    data = (rot_mat*data')';
                    data(:,1) = data(:,1) + class_info.x_mean;
                    data(:,2) = data(:,2) + class_info.y_mean;
                case 'Half-Circle'
                    data = zeros(class_info.num_points,2);
                    curr_i = 1;
                    while curr_i<=class_info.num_points
                        curr_point = [5*(rand-0.5)*(class_info.x_size+class_info.thickness) 5*(rand*0.5)*(class_info.y_size+class_info.thickness)];
                        buffer = (curr_point(1)).^2/(2.5*class_info.x_size).^2 + (curr_point(2)).^2/(2.5*class_info.y_size).^2;
                        if buffer>=1 && buffer <= 1 + class_info.thickness
                            data(curr_i,:) = curr_point;
                            curr_i = curr_i + 1;
                            continue;
                        else
                            continue;
                        end
                    end
                    rot_mat = [cosd(class_info.rot_angle) sind(class_info.rot_angle);-sind(class_info.rot_angle) cosd(class_info.rot_angle)];
                    data = (rot_mat*data')';
                    data(:,1) = data(:,1) + class_info.x_mean;
                    data(:,2) = data(:,2) + class_info.y_mean;
            end
            
        end
        
        function add_class_plot(app, index)
            app.class_plot{end+1} = plot(app.data_axes,app.class_data{end}(:,1),app.class_data{end}(:,2),'MarkerEdgeColor',app.colors{index},'Marker','.','color','none');
        end
        
        function add_class_table(app)
            class_info = app.class_info(end);
            app.class_table.Data(end+1,:) = {class_info.class, class_info.type, class_info.num_points, ...
                                            class_info.x_mean, class_info.y_mean, class_info.x_size, ...
                                            class_info.y_size, class_info.rot_angle, class_info.thickness};
        end
        
        function modify_class_plot(app,index)
            app.class_plot{index}.XData = app.class_data{index}(:,1);
            app.class_plot{index}.YData = app.class_data{index}(:,2);
            app.class_plot{index}.MarkerEdgeColor = app.colors{app.class_info(index).class};
            app.class_plot{index}.Color = 'none';
            app.class_plot{index}.Marker = '.';
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.class_table.ColumnFormat = {[],{'Gaussian','Uniform','Circular','Half-Circle'},[],[],[],[],[],[],[]};
            app.data_axes.XLim = [app.x_min app.x_max];
            app.data_axes.YLim = [app.y_min app.y_max];
            app.axes_table.Data = {'x min' app.x_min;...
                                   'x max' app.x_max;...
                                   'y min' app.y_min;...
                                   'y max' app.y_max};
            app.class_table.Data = {};
            app.colors{1} = [0, 0.4470, 0.7410];
            app.colors{2} = [0.8500, 0.3250, 0.0980];
            app.colors{3}=[0.9290, 0.6940, 0.1250];
            app.colors{4}=[0.4940, 0.1840, 0.5560];
            app.colors{5}=[0.4660, 0.6740, 0.1880];
            app.colors{6}=[0.3010, 0.7450, 0.9330];
            app.colors{7}=[0.6350, 0.0780, 0.1840];
        end

        % Close request function: Generate2DDataUIFigure
        function Generate2DDataUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: add_class_button
        function add_class_buttonButtonPushed(app, event)
            max_class = max([app.class_info(:).class]);
            if isempty(max_class)
                curr_class=1;
            else
                curr_class = max_class + 1;
                if curr_class>app.max_class_count
                    curr_class = app.max_class_count;
                end
            end
            app.class_info(app.curr_counter).class = curr_class;
            app.class_info(app.curr_counter).type = app.class_type_drop_down.Value;
            app.class_info(app.curr_counter).type = app.class_type_drop_down.Value;
            app.class_info(app.curr_counter).num_points = app.start_num_points;
            app.class_info(app.curr_counter).x_mean = app.start_x_mean;
            app.class_info(app.curr_counter).y_mean = app.start_y_mean;
            app.class_info(app.curr_counter).x_size = app.start_x_size;
            app.class_info(app.curr_counter).y_size = app.start_y_size;
            app.class_info(app.curr_counter).rot_angle = app.start_rot_angle;
            app.class_info(app.curr_counter).thickness = app.start_thickness;
            app.class_data{end+1} = app.generate_class_data(app.class_info(end));            
            app.add_class_plot(curr_class);
            app.add_class_table;
            app.curr_counter = app.curr_counter + 1;
        end

        % Cell edit callback: axes_table
        function axes_tableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            % Return previous value if user does not enter proper value;
            if isnan(newData)
                if indices(1) == 1
                    app.axes_table.Data{indices(1),indices(2)} = app.x_min;
                    return;
                elseif indices(1) == 2
                    app.axes_table.Data{indices(1),indices(2)} = app.x_max;
                    return;
                elseif indices(1) == 3
                    app.axes_table.Data{indices(1),indices(2)} = app.y_min;
                    return;
                elseif indices(1) == 4
                    app.axes_table.Data{indices(1),indices(2)} = app.y_max;
                    return;
                end
            end
            
            % Return previous value if illogical (xmin > xmax for example)
            if indices(1) == 1 && newData>app.x_max
                app.axes_table.Data{indices(1),indices(2)} = app.x_min;
                return;
            elseif indices(1) == 2 && newData<app.x_min
                app.axes_table.Data{indices(1),indices(2)} = app.x_max;
                return;
            elseif indices(1) == 3 && newData>app.y_max
                app.axes_table.Data{indices(1),indices(2)} = app.y_min;
                return;
            elseif indices(1) == 4 && newData<app.y_min
                app.axes_table.Data{indices(1),indices(2)} = app.y_max;
                return;
            end
            
            % Apply axes changes
            if indices(1) == 1
                app.x_min = newData;
            elseif indices(1) == 2
                app.x_max = newData;
            elseif indices(1) == 3
                app.y_min = newData;
            elseif indices(1) == 4
                app.y_max = newData                
            end
            app.data_axes.XLim = [app.x_min app.x_max];
            app.data_axes.YLim = [app.y_min app.y_max];
        end

        % Cell edit callback: class_table
        function class_tableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(2) == 1
                if isnan(newData)
                    app.class_table.Data{indices(1),indices(2)} = app.class_info(indices(1)).class;
                    return;
                end
                if newData<1
                    app.class_table.Data{indices(1),indices(2)} = app.class_info(indices(1)).class;
                    return;
                end
                app.class_info(indices(1)).class = newData;
                app.class_data{indices(1)} = app.generate_class_data(app.class_info(indices(1)));
                app.modify_class_plot(indices(1));
            elseif indices(2) == 2
                if isnan(newData)
                    app.class_table.Data{indices(1),indices(2)} = app.class_info(indices(1)).type;
                    return;
                end
                app.class_info(indices(1)).type = newData;
                app.class_data{indices(1)} = app.generate_class_data(app.class_info(indices(1)));
                app.modify_class_plot(indices(1));
            elseif indices(2) == 3
                if isnan(newData)
                    app.class_table.Data{indices(1),indices(2)} = app.class_info(indices(1)).num_points;
                    return;
                end
                app.class_info(indices(1)).num_points = newData;
                app.class_data{indices(1)} = app.generate_class_data(app.class_info(indices(1)));
                app.modify_class_plot(indices(1));
            elseif indices(2) == 4
                if isnan(newData)
                    app.class_table.Data{indices(1),indices(2)} = app.class_info(indices(1)).x_mean;
                    return;
                end
                app.class_info(indices(1)).x_mean = newData;
                app.class_data{indices(1)} = app.generate_class_data(app.class_info(indices(1)));
                app.modify_class_plot(indices(1));
            elseif indices(2) == 5
                if isnan(newData)
                    app.class_table.Data{indices(1),indices(2)} = app.class_info(indices(1)).y_mean;
                    return;
                end
                app.class_info(indices(1)).y_mean = newData;
                app.class_data{indices(1)} = app.generate_class_data(app.class_info(indices(1)));
                app.modify_class_plot(indices(1));
            elseif indices(2) == 6
                if isnan(newData)
                    app.class_table.Data{indices(1),indices(2)} = app.class_info(indices(1)).x_size;
                    return;
                end
                if newData<0
                    app.class_table.Data{indices(1),indices(2)} = app.class_info(indices(1)).x_size;
                    return;
                end
                app.class_info(indices(1)).x_size = newData;
                app.class_data{indices(1)} = app.generate_class_data(app.class_info(indices(1)));
                app.modify_class_plot(indices(1));
            elseif indices(2) == 7
                if isnan(newData)
                    app.class_table.Data{indices(1),indices(2)} = app.class_info(indices(1)).y_size;
                    return;
                end
                if newData<0
                    app.class_table.Data{indices(1),indices(2)} = app.class_info(indices(1)).y_size;
                    return;
                end
                app.class_info(indices(1)).y_size = newData;
                app.class_data{indices(1)} = app.generate_class_data(app.class_info(indices(1)));
                app.modify_class_plot(indices(1));
            elseif indices(2) == 8
                if isnan(newData)
                    app.class_table.Data{indices(1),indices(2)} = app.class_info(indices(1)).rot_angle;
                    return;
                end
                app.class_info(indices(1)).rot_angle = newData;
                app.class_data{indices(1)} = app.generate_class_data(app.class_info(indices(1)));
                app.modify_class_plot(indices(1));
            elseif indices(2) == 9
                if isnan(newData)
                    app.class_table.Data{indices(1),indices(2)} = app.class_info(indices(1)).thickness;
                    return;
                end
                if newData<0
                    app.class_table.Data{indices(1),indices(2)} = app.class_info(indices(1)).thickness;
                    return;
                end
                app.class_info(indices(1)).thickness = newData;
                app.class_data{indices(1)} = app.generate_class_data(app.class_info(indices(1)));
                app.modify_class_plot(indices(1));
            end
        end

        % Button pushed function: remove_class_button
        function remove_class_buttonButtonPushed(app, event)
            if app.current_selected_row == 0
                return;
            end
            current_row = app.current_selected_row;
            app.class_info(current_row) = [];
            app.class_data(current_row) = [];
            delete(app.class_plot{current_row});
            app.class_plot(current_row) = [];
            app.class_table.Data(current_row,:) = [];
            app.current_selected_row = 0;
            app.curr_counter = app.curr_counter - 1;
        end

        % Cell selection callback: class_table
        function class_tableCellSelection(app, event)
            indices = event.Indices;
            app.current_selected_row = indices(1);
        end

        % Button pushed function: export_button
        function export_buttonButtonPushed(app, event)
            data_to_export = {};
            for i = 1 : length(app.class_data)
                data_to_export{i} = [repmat(app.class_info(i).class,[size(app.class_data{i},1) 1]),app.class_data{i}];
            end
            data_to_export = cell2mat(data_to_export');
            csvwrite('output.csv',data_to_export);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Generate2DDataUIFigure = uifigure('Visible', 'off');
            app.Generate2DDataUIFigure.Name = 'Generate 2D Data';
            app.Generate2DDataUIFigure.CloseRequestFcn = createCallbackFcn(app, @Generate2DDataUIFigureCloseRequest, true);
            app.data_axes = uiaxes(app.Generate2DDataUIFigure);
            title(app.data_axes, 'Data')
            xlabel(app.data_axes, 'X')
            ylabel(app.data_axes, 'Y')
            app.class_table = uitable(app.Generate2DDataUIFigure);
            app.class_table.ColumnName = {'Class #'; 'Type'; '# points'; 'x-mean'; 'y-mean'; 'x-size'; 'y-size'; 'angle'; 'thickness'};
            app.class_table.RowName = {};
            app.class_table.ColumnEditable = true;
            app.class_table.CellEditCallback = createCallbackFcn(app, @class_tableCellEdit, true);
            app.class_table.CellSelectionCallback = createCallbackFcn(app, @class_tableCellSelection, true);
            app.axes_table = uitable(app.Generate2DDataUIFigure);
            app.axes_table.ColumnName = {'Axes'; 'Value'};
            app.axes_table.RowName = {''};
            app.axes_table.ColumnEditable = [false true];
            app.axes_table.CellEditCallback = createCallbackFcn(app, @axes_tableCellEdit, true);
            app.ControlPanel = uipanel(app.Generate2DDataUIFigure);
            app.ControlPanel.Title = 'Control';
            app.export_button = uibutton(app.ControlPanel, 'push');
            app.export_button.ButtonPushedFcn = createCallbackFcn(app, @export_buttonButtonPushed, true);
            app.export_button.Text = 'Export';
            app.add_class_button = uibutton(app.ControlPanel, 'push');
            app.add_class_button.ButtonPushedFcn = createCallbackFcn(app, @add_class_buttonButtonPushed, true);
            app.add_class_button.Text = 'Add Class';
            app.remove_class_button = uibutton(app.ControlPanel, 'push');
            app.remove_class_button.ButtonPushedFcn = createCallbackFcn(app, @remove_class_buttonButtonPushed, true);
            app.remove_class_button.Text = 'Remove selected class';
            app.class_type_drop_down = uidropdown(app.ControlPanel);
            app.class_type_drop_down.Items = {'Gaussian', 'Uniform', 'Circular', 'Half-Circle'};
            app.class_type_drop_down.Value = 'Gaussian';
            % Show the figure after all components are created
            app.Generate2DDataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = generate_2d_data

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Generate2DDataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Generate2DDataUIFigure)
        end
    end
end